classdef mPigeon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mPigeonPanel  matlab.ui.Figure
        Image         matlab.ui.control.Image
        InitButton    matlab.ui.control.Button
        TestButton    matlab.ui.control.Button
        CloseButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        Port % Parameters to be fed to the experimental program
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitButton
        function InitButtonPushed(app, event)
            port = serialportlist;
            port = port{end};
            app.Port = hf.initRelayBoard(port);
            if app.Port ~= -1
                app.TestButton.Enable = 'on';
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            port = app.Port;
            hf.silverbox(port, 'light-on');
            WaitSecs(.25);
            hf.silverbox(port, 'feeder');
            WaitSecs(.25);
            hf.silverbox(port, 'feeder');
            hf.silverbox(port, 'light-off');
            app.CloseButton.Enable = 'on';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            global serialPort
            serialPort = app.Port;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mPigeonPanel = uifigure('Visible', 'off');
            app.mPigeonPanel.Name = 'mPigeon';
            app.Image = uiimage(app.mPigeonPanel);
            app.Image.ImageSource = 'mPigeon.jpg';
            app.InitButton = uibutton(app.mPigeonPanel, 'push');
            app.InitButton.ButtonPushedFcn = createCallbackFcn(app, @InitButtonPushed, true);
            app.InitButton.Text = 'Init';
            app.TestButton = uibutton(app.mPigeonPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Text = 'Test';
            app.CloseButton = uibutton(app.mPigeonPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Enable = 'off';
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.mPigeonPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mPigeon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mPigeonPanel)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mPigeonPanel)
        end
    end
end