classdef controlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pControlPanel           matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ExperimentLabel         matlab.ui.control.Label
        ExperimentEditField     matlab.ui.control.EditField
        SubjectLabel            matlab.ui.control.Label
        SubjectEditField        matlab.ui.control.EditField
        TaskDropDownLabel       matlab.ui.control.Label
        TaskDropDown            matlab.ui.control.DropDown
        ITIsEditFieldLabel      matlab.ui.control.Label
        ITIsEditField           matlab.ui.control.EditField
        TimeoutsEditFieldLabel  matlab.ui.control.Label
        TimeoutsEditField       matlab.ui.control.EditField
        FREditFieldLabel        matlab.ui.control.Label
        FREditField             matlab.ui.control.EditField
        PelletsEditFieldLabel   matlab.ui.control.Label
        PelletsEditField        matlab.ui.control.EditField
        EmptyField              matlab.ui.control.EditField
        EmptyField_2            matlab.ui.control.EditField
        GoButton                matlab.ui.control.Button
        QuitButton              matlab.ui.control.Button
        SummaryArea             matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        
        function saveControlPrefs(app)
            controlPanelPrefs = struct();
            controlPanelPrefs.ExperimentEditField = app.ExperimentEditField.Value;
            controlPanelPrefs.SubjectEditField = app.SubjectEditField.Value;
            controlPanelPrefs.ITIsEditField = app.ITIsEditField.Value;
            controlPanelPrefs.TimeoutsEditField = app.TimeoutsEditField.Value;
            controlPanelPrefs.FREditField = app.FREditField.Value;
            controlPanelPrefs.PelletsEditField = app.PelletsEditField.Value;
            controlPanelPrefs.TaskDropDown = find(strcmp(app.TaskDropDown.Value, app.TaskDropDown.Items));
            controlPanelPrefs.Message = app.SummaryArea.Value;
            save('controlPanelPrefs.mat', 'controlPanelPrefs');
        end
        
        function loadParameters(app)
            global expParameters;
            expParameters.Experiment = app.ExperimentEditField.Value;
            expParameters.Subject = app.SubjectEditField.Value;
            expParameters.Task = app.TaskDropDown.Value;
            expParameters.ITI = parseRange(app, app.ITIsEditField.Value);
            expParameters.Timeout = parseRange(app, app.TimeoutsEditField.Value);
            expParameters.FR = parseRange(app, app.FREditField.Value);
            expParameters.Pellets = parseRange(app, app.PelletsEditField.Value);
        end
        
        
    end
    
    methods (Access = public)
        function range = parseRange(~, rangeString)
            hyp = strfind(rangeString, '-');
            if isempty(hyp)
                range = str2double(rangeString);
            else
                range = str2double(rangeString(1:(hyp-1))):str2double(rangeString(hyp+1:end));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            matFile = dir('controlPanelPrefs.mat');
            if isempty(matFile)
            %Write default options
                controlPanelPrefs = struct();
                controlPanelPrefs.ExperimentEditField = 'BlankExperiment';
                controlPanelPrefs.SubjectEditField = 'PassengerPigeon';
                controlPanelPrefs.ITIsEditField = '0';
                controlPanelPrefs.TimeoutsEditField = '0';
                controlPanelPrefs.FREditField = '0';
                controlPanelPrefs.PelletsEditField = '1';
                controlPanelPrefs.TaskDropDown = 1;
                controlPanelPrefs.Message = '';
                save('controlPanelPrefs.mat', 'controlPanelPrefs');
            else
                load( 'controlPanelPrefs.mat', 'controlPanelPrefs')
            end
            %Check if there are csv files; populate dropdown menu
            taskFiles = dir('./tasks/');
            taskFiles = taskFiles(3:end);
            if isempty(taskFiles)
                errordlg('No task files were found', 'Error!');
                delete(app)
                return
            else
                taskMenu = {taskFiles.name};
                app.TaskDropDown.Items = taskMenu;
            end
            %Populate panel
            app.ExperimentEditField.Value = controlPanelPrefs.ExperimentEditField;
            app.SubjectEditField.Value = controlPanelPrefs.SubjectEditField;
            app.ITIsEditField.Value = controlPanelPrefs.ITIsEditField;
            app.TimeoutsEditField.Value = controlPanelPrefs.TimeoutsEditField;
            app.FREditField.Value = controlPanelPrefs.FREditField;
            app.PelletsEditField.Value = controlPanelPrefs.PelletsEditField;
            %try/catch to avoid THE bug
            try
                app.TaskDropDown.Value = taskMenu{controlPanelPrefs.TaskDropDown};
            catch
                app.TaskDropDown.Value = taskMenu{1};
            end
            
            
            %This bit of code only executes after an experiment has ran
            global expRunning
            global message
            if ~isempty(expRunning)
                app.SummaryArea.BackgroundColor = [.99, .49, .61];
                app.SummaryArea.Value = message;
            else
                app.SummaryArea.Value = controlPanelPrefs.Message;
            end
                
            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            global expRunning
            expRunning = [];
            %save controlPanelPrefs and load parameters
            saveControlPrefs(app);
            delete(app)
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            global expRunning
            saveControlPrefs(app);
            loadParameters(app);
            if ~isempty(expRunning)
                expRunning = [];
            else
                expRunning = 1;
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pControlPanel = uifigure('Visible', 'off');
            app.pControlPanel.Name = 'Experiment Control Panel';
            app.UIAxes = uiaxes(app.pControlPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ExperimentLabel = uilabel(app.pControlPanel);
            app.ExperimentLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.pControlPanel, 'text');
            app.SubjectLabel = uilabel(app.pControlPanel);
            app.SubjectLabel.Text = 'Subject:';
            app.SubjectEditField = uieditfield(app.pControlPanel, 'text');
            app.TaskDropDownLabel = uilabel(app.pControlPanel);
            app.TaskDropDownLabel.Text = 'Task:';
            app.TaskDropDown = uidropdown(app.pControlPanel);
            app.ITIsEditFieldLabel = uilabel(app.pControlPanel);
            app.ITIsEditFieldLabel.Text = 'ITI (s)';
            app.ITIsEditField = uieditfield(app.pControlPanel, 'text');
            app.TimeoutsEditFieldLabel = uilabel(app.pControlPanel);
            app.TimeoutsEditFieldLabel.Text = 'Timeout (s)';
            app.TimeoutsEditField = uieditfield(app.pControlPanel, 'text');
            app.FREditFieldLabel = uilabel(app.pControlPanel);
            app.FREditFieldLabel.Text = 'FR';
            app.FREditField = uieditfield(app.pControlPanel, 'text');
            app.PelletsEditFieldLabel = uilabel(app.pControlPanel);
            app.PelletsEditFieldLabel.Text = 'Pellets';
            app.PelletsEditField = uieditfield(app.pControlPanel, 'text');
            app.EmptyField = uieditfield(app.pControlPanel, 'text');
            app.EmptyField_2 = uieditfield(app.pControlPanel, 'text');
            app.GoButton = uibutton(app.pControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.QuitButton = uibutton(app.pControlPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.SummaryArea = uitextarea(app.pControlPanel);
            app.SummaryArea.Editable = 'off';
            % Show the figure after all components are created
            app.pControlPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pControlPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pControlPanel)
        end
    end
end