classdef expManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        experimentManager       matlab.ui.Figure
        RunButton               matlab.ui.control.Button
        ReplaceButton           matlab.ui.control.Button
        ExperimentsButtonGroup  matlab.ui.container.ButtonGroup
        slot1                   matlab.ui.control.ToggleButton
        slot2                   matlab.ui.control.ToggleButton
        slot3                   matlab.ui.control.ToggleButton
        slot4                   matlab.ui.control.ToggleButton
        QuitButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        expFiles
        expPaths
        selectedButton
        appDir
    end
    
    methods (Access = private)
        function updateButtons(app)
            %Populate slot buttons
            for s = 1:4
                app.(sprintf('slot%d', s)).Text = app.expFiles{s};
            end
        end
        
        function selection = checkButtons(app)
            selection = find([app.slot1.Value, app.slot2.Value, app.slot3.Value, app.slot4.Value]);
        end
        
        function saveInfo(app)
            files = app.expFiles;
            paths = app.expPaths;
            save('expData.mat', 'files', 'paths'); %save
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appDir = cd;
            if isempty(dir('expData.mat'))
                files = {'', '', '', ''};
                paths = {'', '', '', ''};
                save('expData.mat', 'files', 'paths');
            end
            load('expData.mat')
            app.expFiles = files;
            app.expPaths = paths;
            app.selectedButton = checkButtons(app);
            updateButtons(app);
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            [expFile, expPath] = uigetfile('*.m');
            drawnow;
            figure(app.experimentManager)
            if expFile
                app.expFiles{app.selectedButton} = expFile;
                app.expPaths{app.selectedButton} = expPath;
                updateButtons(app);
            else
                app.expFiles{app.selectedButton} = '';
                app.expPaths{app.selectedButton} = '';
                updateButtons(app);
            end
            saveInfo(app);
        end

        % Selection changed function: ExperimentsButtonGroup
        function ExperimentsButtonGroupSelectionChanged(app, event)
            app.selectedButton = checkButtons(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~isempty(app.expPaths{app.selectedButton})
                cd(app.expPaths{app.selectedButton});
                run(app.expFiles{app.selectedButton});
                drawnow;
                figure(app.experimentManager)
                cd(app.appDir);
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.experimentManager = uifigure('Visible', 'off');
            app.experimentManager.Name = 'Experiment Manager';
            app.RunButton = uibutton(app.experimentManager, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ReplaceButton = uibutton(app.experimentManager, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.ExperimentsButtonGroup = uibuttongroup(app.experimentManager);
            app.ExperimentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentsButtonGroupSelectionChanged, true);
            app.ExperimentsButtonGroup.Title = 'Experiments';
            app.slot1 = uitogglebutton(app.ExperimentsButtonGroup);
            app.slot1.Text = 'Button';
            app.slot1.Value = true;
            app.slot2 = uitogglebutton(app.ExperimentsButtonGroup);
            app.slot2.Text = 'Button2';
            app.slot3 = uitogglebutton(app.ExperimentsButtonGroup);
            app.slot3.Text = 'Button3';
            app.slot4 = uitogglebutton(app.ExperimentsButtonGroup);
            app.slot4.Text = 'Button4';
            app.QuitButton = uibutton(app.experimentManager, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.experimentManager.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.experimentManager)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.experimentManager)
        end
    end
end